classdef F1_2018_Tel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DataPathEditFieldLabel  matlab.ui.control.Label
        DataPathEditField       matlab.ui.control.EditField
        OnOffButton             matlab.ui.control.StateButton
        DispEditFieldLabel      matlab.ui.control.Label
        DispEditField           matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OnOffButton
        function OnOffButtonValueChanged(app, event)
            value = app.OnOffButton.Value;
            
            filename = [app.DataPathEditField.Value, 'log.mat'];    
            c = 0;
            dataR = (zeros(1342, 1));
            dataR = dataR';
            udpr = dsp.UDPReceiver( "ReceiveBufferSize", 67108864 ,"LocalIPPort", 20777 , "MaximumMessageLength" ,1342);
%             udpr.UDPReceiver.RemoteIPAddress = app.IPEditField.Value;


            while  value == 1
%               
                    c = c + 1;
    
                    read = double(udpr());


                    [x, ~] = size(read);
                    a = (zeros(1341 - x, 1));
                    a = a';
                    read = read';
                    dataR = [x, read ,a]; 
                    data(c, :) = dataR;
                    disp(size(data))
    %               disp(value);
                    pause(1/3000);
        
                    app.DispEditField.Value = x;
                
                    value = app.OnOffButton.Value;   


            end
            

%             [fnm,pth] = uiputfile(filename);
%             if ischar(fnm)
%             save(fullfile(pth,fnm),'app')
%             end
            release(udpr);
            save(filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataPathEditFieldLabel = uilabel(app.UIFigure);
            app.DataPathEditFieldLabel.Text = 'Data Path';
            app.DataPathEditField = uieditfield(app.UIFigure, 'text');
            app.DataPathEditField.Value = 'D:\Documents\Dottorato\Software\Progetti_Matlab\App\F1_2018_Tel\';
            app.OnOffButton = uibutton(app.UIFigure, 'state');
            app.OnOffButton.ValueChangedFcn = createCallbackFcn(app, @OnOffButtonValueChanged, true);
            app.OnOffButton.Text = 'On/Off';
            app.DispEditFieldLabel = uilabel(app.UIFigure);
            app.DispEditFieldLabel.Text = 'Disp';
            app.DispEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F1_2018_Tel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end